classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ColorCorrectionAppLabel   matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        InterpolationMapTab       matlab.ui.container.Tab
        ConvertToRgbButton        matlab.ui.control.Button
        InterpolationMapPanel     matlab.ui.container.Panel
        ClearMapButton            matlab.ui.control.Button
        InterpolationTable        matlab.ui.control.Table
        SelectPixelPanel          matlab.ui.container.Panel
        ClearButton               matlab.ui.control.Button
        AddToMapButton            matlab.ui.control.Button
        RedValueLabel             matlab.ui.control.Label
        SelectedRgbLabel          matlab.ui.control.Label
        SelectRgbButton           matlab.ui.control.Button
        SavedScalarValueLabel     matlab.ui.control.Label
        SavedScalarLabel          matlab.ui.control.Label
        CurrentScalarValueLabel   matlab.ui.control.Label
        CurrentScalarLabel        matlab.ui.control.Label
        ImagePlaceholderAxes      matlab.ui.control.UIAxes
        StatusLabel               matlab.ui.control.Label
        UploadMonoImageButton     matlab.ui.control.Button
        ColorCorrectionMatrixTab  matlab.ui.container.Tab
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadMonoImageButton
        function UploadMonoImageButtonPushed(app, event)
            % Open a file selection dialog for image files.
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files'});
            
            % Check if the user selected a file.
            if isequal(file, 0)
                return; % User canceled, do nothing.
            end
            
            % Construct the full file path.
            filePath = fullfile(path, file);
            
            % Load the image.
            img = imread(filePath);

            % Validate image is monochrome.
            % If valid:
                % Display image
                % Change status to '<filename>'
            % else:
                % do uialert
                % change status label to 'try again'
            
            % Display the image in the panel, and update text on status
            % label and button.
            imshow(img, 'Parent', app.ImagePlaceholderAxes);
            app.UploadMonoImageButton.Text = ['Change image']
            app.StatusLabel.Text = ['Uploaded ', file];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InterpolationMapTab = uitab(app.TabGroup);
            app.InterpolationMapTab.Title = 'Interpolation Map';
            app.UploadMonoImageButton = uibutton(app.InterpolationMapTab, 'push');
            app.UploadMonoImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadMonoImageButtonPushed, true);
            app.UploadMonoImageButton.Text = 'Upload monochrome image';
            app.StatusLabel = uilabel(app.InterpolationMapTab);
            app.StatusLabel.Text = 'No file selected';
            app.SelectPixelPanel = uipanel(app.InterpolationMapTab);
            app.SelectPixelPanel.Title = 'Select Pixel Panel';
            app.ImagePlaceholderAxes = uiaxes(app.SelectPixelPanel);
            app.ImagePlaceholderAxes.XTick = [];
            app.ImagePlaceholderAxes.YTick = [];
            app.CurrentScalarLabel = uilabel(app.SelectPixelPanel);
            app.CurrentScalarLabel.Text = 'Current scalar:';
            app.CurrentScalarValueLabel = uilabel(app.SelectPixelPanel);
            app.CurrentScalarValueLabel.Text = '<###>';
            app.SavedScalarLabel = uilabel(app.SelectPixelPanel);
            app.SavedScalarLabel.Text = 'Saved scalar:';
            app.SavedScalarValueLabel = uilabel(app.SelectPixelPanel);
            app.SavedScalarValueLabel.Text = '<###>';
            app.SelectRgbButton = uibutton(app.SelectPixelPanel, 'push');
            app.SelectRgbButton.Text = 'Select RGB';
            app.SelectedRgbLabel = uilabel(app.SelectPixelPanel);
            app.SelectedRgbLabel.Text = 'RGB values:';
            app.RedValueLabel = uilabel(app.SelectPixelPanel);
            app.RedValueLabel.Text = '<###>';
            app.AddToMapButton = uibutton(app.SelectPixelPanel, 'push');
            app.AddToMapButton.Text = 'Add to map';
            app.ClearButton = uibutton(app.SelectPixelPanel, 'push');
            app.ClearButton.Text = 'Clear';
            app.InterpolationMapPanel = uipanel(app.InterpolationMapTab);
            app.InterpolationMapPanel.Title = 'Interpolation Map';
            app.InterpolationTable = uitable(app.InterpolationMapPanel);
            app.InterpolationTable.ColumnName = {'Scalar'; 'R'; 'G'; 'B'};
            app.InterpolationTable.RowName = {};
            app.ClearMapButton = uibutton(app.InterpolationMapPanel, 'push');
            app.ClearMapButton.Text = 'Clear map';
            app.ConvertToRgbButton = uibutton(app.InterpolationMapTab, 'push');
            app.ConvertToRgbButton.Text = 'Convert to RGB';
            app.ColorCorrectionMatrixTab = uitab(app.TabGroup);
            app.ColorCorrectionMatrixTab.Title = 'Color Correction Matrix';
            app.ColorCorrectionAppLabel = uilabel(app.UIFigure);
            app.ColorCorrectionAppLabel.Text = 'Color Correction App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end